classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        Button                      matlab.ui.control.Button
        SliderLabel                 matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        GaugeLabel                  matlab.ui.control.Label
        Gauge                       matlab.ui.control.Gauge
        UITable                     matlab.ui.control.Table
        NcFile                      matlab.ui.control.EditField
        UploadanNCfileButton        matlab.ui.control.Button
        CSVsFolder                  matlab.ui.control.EditField
        ChoosefolderwithCSVmodelsButton  matlab.ui.control.Button
        ChooseEnsembleForCombinedModelButtonGroup  matlab.ui.container.ButtonGroup
        OriginButton                matlab.ui.control.RadioButton
        ClusterBasedEnsembleButton  matlab.ui.control.RadioButton
        ChooseModelDropDownLabel    matlab.ui.control.Label
        ChooseModelDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        values; % Description
        zValues;
        pathToNcFile;
        pathToCBEs;
        csvNameTemplate;
        theNcFile;
    end
    
    methods (Access = private)
        
        function generateMap(app, hour)
            cla(app.UIAxes,'reset');
            axis(app.UIAxes,'off');
            load coastlines
            % Construct map in regular axes
            ax1 = worldmap('Europe');
            hPlot = plotm(coastlat, coastlon);
            land = shaperead('landareas', 'UseGeoCoords', true);
            %geoshow(gca, land, 'FaceColor', [0.5 0.7 0.5]);
            geoshow([land.Lat],[land.Lon],'Color','k')
            
            % Copy data into "uiaxes"
            app.theNcFile = ncinfo(app.pathToNcFile);
            chimere_ozone = {app.theNcFile.Variables.Name};
            table = ncread(app.pathToNcFile,chimere_ozone{1});
            [X] = ncread(app.pathToNcFile, 'lat')'; % create X value
            [Y] = ncread(app.pathToNcFile, 'lon')';% create Y values
            % create a mesh of valuesS
            [Y] = Y(1:698);
            [X] = X(1:398);
            X = double(X);
            Y = double(Y)';
            [X,Y] = meshgrid(X, Y);
            % Remove original axes
            
  
            Z=app.zValues(:,:,app.Slider.Value);
            c=colorbar(app.UIAxes);
            c.Label.String = 'Ozone concentration (ppbv )';
            title(c, 'ppbv')
            surfm(X,Y,Z, 'EdgeColor','none','FaceAlpha', 0.8);
            c.Position(4) = 0.6*c.Position(4);
            c.Position(1) = 0.95*c.Position(1);
            c.Position = c.Position + [.05 .2 0 0];
            copyobj(ax1.Children, app.UIAxes);
            fig1 = ax1.Parent;
            delete(fig1);
            
        end
        
        function readValuesToTable(app, pathToFiles)
            
            app.values= [];
            app.zValues= [];
            if app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject.Tag == "1" 
                nameTemplate = '24HR_Orig_*.csv';
            elseif app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject.Tag == "2"
                nameTemplate = '24HR_CBE_*.csv';
            end
            
            %nameTemplate = '24HR_Orig_*.csv';
            disp(pathToFiles)
            dirTemplate = strcat(pathToFiles,nameTemplate);
            disp(dirTemplate)
            sprintf("selected button: %s",app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject.Tag);
            fileDirectory = dir(dirTemplate);
            for k = 1 : length(fileDirectory)
                formatSpec = "Read Z %d : %s";
                file = fileDirectory(k).name;
                Z=[];
                Z = readtable([pathToFiles,file]);
                Z = table2array(Z);
                Z=Z';
                typeof = size(Z);
                matrixSize= "%d x %d";
                a = compose(matrixSize, typeof(1,1),typeof(1,2));
                value=compose(formatSpec,k,a);
                app.values=[app.values; value];
                app.zValues (:,:,k)=[Z];
            end
            app.Slider.Limits = [1 length(fileDirectory)];
            
            app.UITable.Data = [app.values];
        end
        
        function collectModels(app)
            variables = {app.theNcFile.Variables.Name};
            app.ChooseModelDropDown.Items ={};
            for i=1 : length(variables)
                app.ChooseModelDropDown.Items = [app.ChooseModelDropDown.Items variables(i)];
        
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            clf;
            
            readValuesToTable(app, app.CSVsFolder.Value)
            
            generateMap(app,app.Slider.Value);
            collectModels(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            newval = app.Slider.Value;                         %get value from the slider
            newval = round(newval);                         %round off this value
            set(app.Slider, 'Value', newval);
            generateMap(app,app.Slider.Value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Gauge.Value = changingValue;
 
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Button pushed function: UploadanNCfileButton
        function UploadanNCfileButtonPushed(app, event)
            [file, path] = uigetfile('*.nc');
            [app.NcFile.Value, app.pathToNcFile] = deal(fullfile(path, file));

        end

        % Button pushed function: ChoosefolderwithCSVmodelsButton
        function ChoosefolderwithCSVmodelsButtonPushed(app, event)
            path = uigetdir();
            app.CSVsFolder.Value = strcat(path,"/");
          
        end

        % Selection changed function: 
        % ChooseEnsembleForCombinedModelButtonGroup
        function ChooseEnsembleForCombinedModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject;
            disp(selectedButton)
        end

        % Callback function
        function ChoseModelButtonGroupSizeChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 25];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [1 5 10 15 20];
            app.Slider.Value = 1;
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [1 25];
            app.Gauge.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.NcFile = uieditfield(app.UIFigure, 'text');
            app.UploadanNCfileButton = uibutton(app.UIFigure, 'push');
            app.UploadanNCfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadanNCfileButtonPushed, true);
            app.UploadanNCfileButton.Text = 'Upload an NC file :';
            app.CSVsFolder = uieditfield(app.UIFigure, 'text');
            app.CSVsFolder.Value = 'select a name template for csv files';
            app.ChoosefolderwithCSVmodelsButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderwithCSVmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderwithCSVmodelsButtonPushed, true);
            app.ChoosefolderwithCSVmodelsButton.Text = {'Choose folder with '; 'CSV models'};
            app.ChooseEnsembleForCombinedModelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseEnsembleForCombinedModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseEnsembleForCombinedModelButtonGroupSelectionChanged, true);
            app.ChooseEnsembleForCombinedModelButtonGroup.Title = 'Choose Ensemble For Combined Model';
            app.OriginButton = uiradiobutton(app.ChooseEnsembleForCombinedModelButtonGroup);
            app.OriginButton.Tag = '1';
            app.OriginButton.Text = 'Origin';
            app.OriginButton.Value = true;
            app.ClusterBasedEnsembleButton = uiradiobutton(app.ChooseEnsembleForCombinedModelButtonGroup);
            app.ClusterBasedEnsembleButton.Tag = '2';
            app.ClusterBasedEnsembleButton.Text = 'Cluster Based Ensemble';
            app.ChooseModelDropDownLabel = uilabel(app.UIFigure);
            app.ChooseModelDropDownLabel.Text = {'Choose  Model'; ''};
            app.ChooseModelDropDown = uidropdown(app.UIFigure);
            app.ChooseModelDropDown.Items = {'Option 11', 'Option 2', 'Option 3', 'Option 4'};
            app.ChooseModelDropDown.Value = 'Option 11';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end